classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FisrtOrderSystemTab             matlab.ui.container.Tab
        DrawResponsButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TEditFieldLabel                 matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        T0EditFieldLabel                matlab.ui.control.Label
        T0EditField                     matlab.ui.control.NumericEditField
        ClearButton                     matlab.ui.control.Button
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditField       matlab.ui.control.NumericEditField
        IntegralActionEditFieldLabel    matlab.ui.control.Label
        IntegralActionEditField         matlab.ui.control.NumericEditField
        ControllerLabel                 matlab.ui.control.Label
        SecondOrderSystemTab            matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        w0EditFieldLabel                matlab.ui.control.Label
        w0E                             matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zE                              matlab.ui.control.NumericEditField
        DrawResponsButton_2             matlab.ui.control.Button
        ClearButton_2                   matlab.ui.control.Button
        ControllerLabel_2               matlab.ui.control.Label
        ProportionalGainLabel           matlab.ui.control.Label
        ProportionalGain                matlab.ui.control.NumericEditField
        IntegralActionEditField_2Label  matlab.ui.control.Label
        IntegralAction                  matlab.ui.control.NumericEditField
        DeravativeActionEditFieldLabel  matlab.ui.control.Label
        DeravativeAction                matlab.ui.control.NumericEditField
        b0EditFieldLabel                matlab.ui.control.Label
        b0E                             matlab.ui.control.NumericEditField
        a1EditFieldLabel                matlab.ui.control.Label
        a1E                             matlab.ui.control.NumericEditField
        a2EditFieldLabel                matlab.ui.control.Label
        a2E                             matlab.ui.control.NumericEditField
        FilteringonderarativeEditFieldLabel  matlab.ui.control.Label
        Filteringonderarative           matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: DrawResponsButton
        function DrawResponsButtonPushed(app, event)
            T = app.TEditField.Value;
            G = app.GEditField.Value;
            T0 = app.T0EditField.Value;
            GP_num = G;
            GP_dem = [T 1];
            GP = tf(GP_num, GP_dem);
            Hr_num = [T 1];
            Hr_dem = [G*T0 0];
            Hr = tf(Hr_num, Hr_dem);
            app.ProportionalGainEditField.Value = T/(G * T0);
            app.IntegralActionEditField.Value = T;
            %app.ControllerTextArea.Value = Hr;
            sys = (Hr * GP)/(1 + Hr * GP);
            [y,t] = step(sys);
            plot(app.UIAxes,t,y);

            %stepplot(app.UIAxes,sys);
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UIAxes.cla;
            app.TEditField.Value = 0;
            app.T0EditField.Value = 0;
            app.GEditField.Value = 0;
            app.ProportionalGainEditField.Value = 0;
            app.IntegralActionEditField.Value = 0;
        end

        % Button pushed function: DrawResponsButton_2
        function DrawResponsButton_2Pushed(app, event)
            w0 = app.w0E.Value;
            z = app.zE.Value;
            b0 = app.b0E.Value;
            a1 = app.a1E.Value;
            a2 = app.a2E.Value;
            
            Ti = a1 - (1/(2 * z * w0));
            Td = (a2 / Ti) - (1 / (2 * z * w0));
            K = (w0 * Ti / (2 * z * b0));
            F = 1 / (2 * z * w0);
            app.IntegralAction.Value = Ti;
            app.DeravativeAction.Value = Td;
            app.ProportionalGain.Value = K;
            app.Filteringonderarative.Value = F;
            %GP = tf(b0, [a2 a1 1]);
            sys = tf(w0^2, [1 2*z*w0 w0^2]);
            [y,t] = step(sys);
            plot(app.UIAxes2,t,y);
            
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.UIAxes2.cla;
            app.IntegralAction.Value = 0;
            app.DeravativeAction.Value = 0;
            app.ProportionalGain.Value = 0;
            app.Filteringonderarative.Value = 0;
            app.w0E.Value = 0;
            app.zE.Value = 0;
            app.b0E.Value = 0;
            app.a1E.Value = 0;
            app.a2E.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FisrtOrderSystemTab = uitab(app.TabGroup);
            app.FisrtOrderSystemTab.Title = 'Fisrt Order System';
            app.FisrtOrderSystemTab.Units = 'pixels';
            app.DrawResponsButton = uibutton(app.FisrtOrderSystemTab, 'push');
            app.DrawResponsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawResponsButtonPushed, true);
            app.DrawResponsButton.Text = 'Draw Respons';
            app.UIAxes = uiaxes(app.FisrtOrderSystemTab);
            title(app.UIAxes, 'Response of the system');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.TEditFieldLabel = uilabel(app.FisrtOrderSystemTab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.FisrtOrderSystemTab, 'numeric');
            app.GEditFieldLabel = uilabel(app.FisrtOrderSystemTab);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.FisrtOrderSystemTab, 'numeric');
            app.T0EditFieldLabel = uilabel(app.FisrtOrderSystemTab);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.FisrtOrderSystemTab, 'numeric');
            app.ClearButton = uibutton(app.FisrtOrderSystemTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ProportionalGainEditFieldLabel = uilabel(app.FisrtOrderSystemTab);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditField = uieditfield(app.FisrtOrderSystemTab, 'numeric');
            app.ProportionalGainEditField.Editable = 'off';
            app.IntegralActionEditFieldLabel = uilabel(app.FisrtOrderSystemTab);
            app.IntegralActionEditFieldLabel.Text = 'Integral Action';
            app.IntegralActionEditField = uieditfield(app.FisrtOrderSystemTab, 'numeric');
            app.IntegralActionEditField.Editable = 'off';
            app.ControllerLabel = uilabel(app.FisrtOrderSystemTab);
            app.ControllerLabel.Text = 'Controller';
            app.SecondOrderSystemTab = uitab(app.TabGroup);
            app.SecondOrderSystemTab.Title = 'Second Order System';
            app.SecondOrderSystemTab.Units = 'pixels';
            app.UIAxes2 = uiaxes(app.SecondOrderSystemTab);
            title(app.UIAxes2, 'Response of the system');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.w0EditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.w0EditFieldLabel.Text = 'w0';
            app.w0E = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.zEditFieldLabel.Text = 'z';
            app.zE = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.DrawResponsButton_2 = uibutton(app.SecondOrderSystemTab, 'push');
            app.DrawResponsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawResponsButton_2Pushed, true);
            app.DrawResponsButton_2.Text = 'Draw Respons';
            app.ClearButton_2 = uibutton(app.SecondOrderSystemTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.ControllerLabel_2 = uilabel(app.SecondOrderSystemTab);
            app.ControllerLabel_2.Text = 'Controller';
            app.ProportionalGainLabel = uilabel(app.SecondOrderSystemTab);
            app.ProportionalGainLabel.Text = 'Proportional Gain';
            app.ProportionalGain = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.ProportionalGain.Editable = 'off';
            app.IntegralActionEditField_2Label = uilabel(app.SecondOrderSystemTab);
            app.IntegralActionEditField_2Label.Text = 'Integral Action';
            app.IntegralAction = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.IntegralAction.Editable = 'off';
            app.DeravativeActionEditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.DeravativeActionEditFieldLabel.Text = 'Deravative Action';
            app.DeravativeAction = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.DeravativeAction.Editable = 'off';
            app.b0EditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.b0EditFieldLabel.Text = 'b0';
            app.b0E = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.a1EditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1E = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.a2EditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2E = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.FilteringonderarativeEditFieldLabel = uilabel(app.SecondOrderSystemTab);
            app.FilteringonderarativeEditFieldLabel.Text = 'Filtering on derarative ';
            app.Filteringonderarative = uieditfield(app.SecondOrderSystemTab, 'numeric');
            app.Filteringonderarative.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end